classdef StateFeedbackController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PredictionHorizonMPCDropDown    matlab.ui.control.DropDown
        PredictionHorizonMPCDropDownLabel  matlab.ui.control.Label
        Set_point2Slider                matlab.ui.control.Slider
        Set_point2SliderLabel           matlab.ui.control.Label
        Set_point1Slider                matlab.ui.control.Slider
        Set_point1SliderLabel           matlab.ui.control.Label
        ControllerTypeDropDown          matlab.ui.control.DropDown
        ControllerTypeDropDownLabel     matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        MPMCompensationDropDown         matlab.ui.control.DropDown
        MPMCompensationDropDownLabel    matlab.ui.control.Label
        ControllerDesignMethodDropDown  matlab.ui.control.DropDown
        ControllerDesignMethodDropDownLabel  matlab.ui.control.Label
        OperatingPointDropDown          matlab.ui.control.DropDown
        OperatingPointDropDownLabel     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access =public)
        pt
        ctrl
        comp
        N_Prediction
        Control_type
        setpt1
        setpt2
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.pt=2;
            app.ctrl=2;
            app.comp=2;
            app.N_Prediction=15;
            app.Control_type=2;
            app.setpt1=0;
            app.setpt2=0;
       

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            global QTank  n_st n_op n_ip% Define global variables
           operating_pt=app.pt;
           ctrl_design_method=app.ctrl; 
           Servo_Problem=app.Control_type;
           compensate=app.comp;
            if (operating_pt == 1)                         % Minimum phase system
                load QTank_MinPhase_Model_1 QTank

            elseif (operating_pt == 2)          % Non-Minimum phase system
                load QTank_NonMinPhase_Model_new QTank
            end
            samp_T=4;
            Ns=500;
            dk=0;

            n_st = 4 ;   % No. of states
            n_op =  2 ;   % No. of measured outputs
            n_ip = 2 ;   % No. of man. inputs
            n_d = 1;  % No. of disturbances

            % Create matrices for saving simulation results
            Xk = zeros(n_st, Ns) ;     % State Profiles
            Uk = zeros(n_ip, Ns) ;     % Input Profiles
            Dk = zeros(n_d, Ns) ;     % Disturbance Profile
            Yk = zeros(n_op, Ns) ;    % Output Profile

            uk = zeros(n_ip, Ns) ;     % Input Profiles (perturbation)
            yk = zeros(n_op, Ns) ;    % Output Profile (perturbation)


            % State Estimator Initialization
            xkpred = zeros(n_st, Ns) ; %  Create Dummy matrices for estimated states
            ek = zeros(n_op, Ns) ;        % Innovation sequence
            ek(:,1) = yk(:,1) - QTank.C_mat * xkpred(:,1) ;
            est_err = zeros(n_st, Ns) ;
            obs_poles=[0.7 0.8 0.9 0.8]';
            Lp_mat = place(QTank.phy', QTank.C_mat', obs_poles)  ;
            Lp_mat = Lp_mat' ;
            % EigenValues= eig( QTank.phy -  Lp_mat * QTank.C_mat );
            %  Innovation Bias Formulation initialization
            xsk = zeros(n_st, Ns) ;     % Matrices for saving terget states
            usk = zeros(n_ip, Ns) ;
            rk = zeros(n_op, Ns) ;
            ek_f = zeros(n_op, Ns) ;        % Filtered Innovation sequence
            alfa_e =0.7;
            phy_e = alfa_e * eye(n_op) ;
            alfa_r = 0.9;
            phy_r = alfa_r * eye(n_op) ;                  % Setpoint filter parameter

            % Marices required in Target state Computation
            Ku_mat = QTank.C_mat * inv(eye(n_st) - QTank.phy) * QTank.gama_u ;
            Ke_mat = QTank.C_mat * inv(eye(n_st) - QTank.phy) * Lp_mat +eye(n_op) ;

            if (ctrl_design_method == 1)
                con_poles = [ 0.7 0.8 0.8 0.9]' ;    % Closed loop poles
                G_mat = place(QTank.phy, QTank.gama_u, con_poles)  ;

            elseif (ctrl_design_method == 2)

                Wx = eye(n_st) ;    % State weighting matrix

                Wu = diag( [1  1]) ;     % Error weighting matrix
                [G_mat, S_inf, EigVal_CL] = dlqr(QTank.phy, QTank.gama_u, Wx, Wu) ;

            elseif (ctrl_design_method == 3)

                kx=1  ;
                Wx = kx* eye(n_st) ;    % State weighting matrix
                %%%%%Error weighting matrix
                ku=1  ;
                Wu =  ku * diag( [1  1]) ;     % Error weighting matrix
                G_mat = Uncon_MPC(QTank.phy, QTank.gama_u,Wx,Wu,app.N_Prediction) ;
                %  EigVal_CL = eig( QTank.phy - QTank.gama_u * G_mat ) ;
            end
            Uk_Hi = [ 5 5 ]' ;
            Uk_Lo = [ 0 0]' ;
            uk_H = Uk_Hi - QTank.Us ;
            uk_L = Uk_Lo - QTank.Us ;
            % Setpoints
            rk = zeros(n_op, Ns) ;    % Deviation setpoint
            Rk = zeros(n_op, Ns) ;    % Setpoint trajectory in original variables

            % Process Initial conditions
            Xk(:,1) = QTank.Xs + 1 * [ -0.2 2 0.5 -0.5]' ;   % non-zero initial condition
            Yk(:,1) = QTank.C_mat * Xk(:,1)  ;

            % Perturbation Model: Initial conditions
            xk(:,1) = Xk(:,1) - QTank.Xs  ;
            yk(:,1) = Yk(:,1) - QTank.Ys   ;
            est_err(:,1) = xkpred(:,1) - xk(:,1);

            % Dynamic simulation of Quadruple Tank system under open loop onditions
            kT = zeros(1, Ns) ;  % Array to record time
            for k = 1 : Ns-1
                % Print sampling instant on screen
                kT(k) = (k-1) * samp_T ;         % update time array

                if (Servo_Problem == 1 )
                    dk=0;
                    if (k>= 100)
                        rk(:,k)= [app.setpt1 app.setpt2]' ;
                        dk = 0 ;
                    else
                        rk(:,k) =  [0 0]'  ;
                        dk = 0 ;
                    end % Servo control

                elseif  (Servo_Problem == 2 )
                    rk(:,k) = zeros(n_op,1) ;
                    if (k >= 100 )
                        dk = -0.3 ;
                    else
                        dk = 0 ;
                    end  % Regulatory control
                end
                Rk(:,k) = QTank.Ys + rk(:,k) ;

                Dk(k) = QTank.Ds + dk ;      % Disturbance input

                % Compute  inputs at sampling instant k using pole placement state feedback controller

                % Generate filtered setpoint
                rk(:,k+1) = phy_r * rk(:,k) + (eye(n_op) - phy_r) * rk(:,k);

                % Compute target input and target states
                usk(:,k) = pinv(Ku_mat) * ( rk(:,k) - Ke_mat * ek_f(:,k) ) ;
                xsk(:,k) =  inv(eye(n_st) - QTank.phy) * ( QTank.gama_u * usk(:,k) +  Lp_mat * ek_f(:,k) ) ;

                if (compensate==1) 
                    uk(:,k) = usk(:,k)  - G_mat * (xkpred(:,k) - xsk(:,k)  )  ;
                elseif  (compensate==2) 
                    uk(:,k) = - G_mat * (xkpred(:,k));% - xsk(:,k)  )  ;
                end

                if ( uk(1,k) <= uk_L(1) )   % Inpose input constraints on uk(1)
                    uk(1,k) = uk_L(1) ;
                elseif ( uk(1,k) >= uk_H(1) )
                    uk(1,k) = uk_H(1) ;
                end
                if ( uk(2,k) <= uk_L(2) ) % Inpose output constraints on uk(2)
                    uk(2,k) = uk_L(2) ;
                elseif ( uk(2,k) >= uk_H(2) )
                    uk(2,k) = uk_H(2) ;
                end
                Uk(:,k) =  uk(:,k) + QTank.Us   ;  % Manipulated input at instant k

                %  Luenberger Predictor computations from instant (k) to (k+1)
                yk(:,k )= Yk(:,k) - QTank.Ys ;
                ek(:,k) =  yk(:,k) - QTank.C_mat *  xkpred(:,k)  ;    % Compute Innovation
                xkpred(:,k+1) = QTank.phy * xkpred(:,k) + QTank.gama_u * uk(:,k) + Lp_mat * ek(:,k)  ;
                % Filtered innovation for innovation bias implementation
                ek_f(:,k+1) = phy_e * ek_f(:,k) + (eye(n_op) - phy_e) *  ek(:,k) ;

                % System Dynamics Simulation from sampling instant k to (k+1)

                % Plant Simulation using discete linear perturbation model
                xk(:,k+1) = QTank.phy * xk(:,k) + QTank.gama_u * uk(:,k) + QTank.gama_d * dk  ;
                yk(:,k+1) = QTank.C_mat * xk(:,k+1);
                Yk(:,k+1) =   yk(:,k+1) + QTank.Ys ;
                est_err(:,k+1) = xkpred(:,k+1) - xk(:,k+1);   % State estimation error
            end
            % specify input at the final instant :
            kT(Ns) = Ns * samp_T ;
            Uk(:,Ns)=   Uk(:,Ns-1) ;    % Input noise case
            Dk(Ns) = Dk(Ns-1) ;
            Rk(:,Ns) =  Rk(:,Ns-1) ;
            usk(:,Ns) = usk(:,Ns-1) ;
            xsk(:,Ns) = xsk(:,Ns-1)    ;
            plot(app.UIAxes,kT, Yk(1,:), '-k', kT, Rk(1,:), '-r')
            plot(app.UIAxes2, kT, Yk(2,:), '-k', kT, Rk(2,:), '-r')
        end

        % Value changed function: OperatingPointDropDown
        function OperatingPointDropDownValueChanged(app, event)
            app.pt = app.OperatingPointDropDown.Value;

        end

        % Value changed function: ControllerDesignMethodDropDown
        function ControllerDesignMethodDropDownValueChanged(app, event)
            app.ctrl= app.ControllerDesignMethodDropDown.Value;

        end

        % Value changed function: ControllerTypeDropDown
        function ControllerTypeDropDownValueChanged(app, event)
            app.Control_type= app.ControllerTypeDropDown.Value;

        end

        % Value changed function: MPMCompensationDropDown
        function MPMCompensationDropDownValueChanged(app, event)
            app.comp= app.MPMCompensationDropDown.Value;

        end

        % Value changed function: Set_point1Slider
        function Set_point1SliderValueChanged(app, event)
            app.setpt1 = app.Set_point1Slider.Value;

        end

        % Value changed function: Set_point2Slider
        function Set_point2SliderValueChanged(app, event)
            app.setpt2 = app.Set_point2Slider.Value;

        end

        % Value changed function: PredictionHorizonMPCDropDown
        function PredictionHorizonMPCDropDownValueChanged(app, event)
            app.N_Prediction = app.PredictionHorizonMPCDropDown.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Controlled Outputs')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Level Tank 1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Controlled Outputs')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Level Tank 2')
            zlabel(app.UIAxes2, 'Z')
            app.OperatingPointDropDownLabel = uilabel(app.UIFigure);
            app.OperatingPointDropDownLabel.Text = 'Operating  Point';
            app.OperatingPointDropDown = uidropdown(app.UIFigure);
            app.OperatingPointDropDown.Items = {'Minimum Phase', 'Non Minimum Phase'};
            app.OperatingPointDropDown.ItemsData = {'1', '2'};
            app.OperatingPointDropDown.ValueChangedFcn = createCallbackFcn(app, @OperatingPointDropDownValueChanged, true);
            app.OperatingPointDropDown.Placeholder = '2';
            app.OperatingPointDropDown.Value = '2';
            app.ControllerDesignMethodDropDownLabel = uilabel(app.UIFigure);
            app.ControllerDesignMethodDropDownLabel.Text = 'Controller Design Method';
            app.ControllerDesignMethodDropDown = uidropdown(app.UIFigure);
            app.ControllerDesignMethodDropDown.Items = {'Pole Placement', 'LQOC', 'Unconstrained MPC'};
            app.ControllerDesignMethodDropDown.ItemsData = {'1', '2', '3'};
            app.ControllerDesignMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerDesignMethodDropDownValueChanged, true);
            app.ControllerDesignMethodDropDown.Placeholder = '2';
            app.ControllerDesignMethodDropDown.Value = '2';
            app.MPMCompensationDropDownLabel = uilabel(app.UIFigure);
            app.MPMCompensationDropDownLabel.Text = 'MPM Compensation';
            app.MPMCompensationDropDown = uidropdown(app.UIFigure);
            app.MPMCompensationDropDown.Items = {'Yes', 'No'};
            app.MPMCompensationDropDown.ItemsData = {'1', '2'};
            app.MPMCompensationDropDown.ValueChangedFcn = createCallbackFcn(app, @MPMCompensationDropDownValueChanged, true);
            app.MPMCompensationDropDown.Placeholder = '2';
            app.MPMCompensationDropDown.Value = '2';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ControllerTypeDropDownLabel = uilabel(app.UIFigure);
            app.ControllerTypeDropDownLabel.Text = 'Controller Type';
            app.ControllerTypeDropDown = uidropdown(app.UIFigure);
            app.ControllerTypeDropDown.Items = {'Servo Control', 'Regulatory'};
            app.ControllerTypeDropDown.ItemsData = {'1', '2'};
            app.ControllerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeDropDownValueChanged, true);
            app.ControllerTypeDropDown.Placeholder = '2';
            app.ControllerTypeDropDown.Value = '2';
            app.Set_point1SliderLabel = uilabel(app.UIFigure);
            app.Set_point1SliderLabel.Text = 'Set_point1';
            app.Set_point1Slider = uislider(app.UIFigure);
            app.Set_point1Slider.Limits = [-5 5];
            app.Set_point1Slider.ValueChangedFcn = createCallbackFcn(app, @Set_point1SliderValueChanged, true);
            app.Set_point1Slider.Value = 1.5;
            app.Set_point2SliderLabel = uilabel(app.UIFigure);
            app.Set_point2SliderLabel.Text = 'Set_point2';
            app.Set_point2Slider = uislider(app.UIFigure);
            app.Set_point2Slider.Limits = [-5 5];
            app.Set_point2Slider.ValueChangedFcn = createCallbackFcn(app, @Set_point2SliderValueChanged, true);
            app.Set_point2Slider.Value = 1.5;
            app.PredictionHorizonMPCDropDownLabel = uilabel(app.UIFigure);
            app.PredictionHorizonMPCDropDownLabel.Text = 'Prediction Horizon  MPC';
            app.PredictionHorizonMPCDropDown = uidropdown(app.UIFigure);
            app.PredictionHorizonMPCDropDown.Items = {'5', '10', '15', '20', '25'};
            app.PredictionHorizonMPCDropDown.ItemsData = {'5 ', '10', '15', '20', '25'};
            app.PredictionHorizonMPCDropDown.ValueChangedFcn = createCallbackFcn(app, @PredictionHorizonMPCDropDownValueChanged, true);
            app.PredictionHorizonMPCDropDown.Placeholder = '20';
            app.PredictionHorizonMPCDropDown.Value = '20';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StateFeedbackController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end