classdef TrialSFC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Button                       matlab.ui.control.Button
        OperatingPointDropDown       matlab.ui.control.DropDown
        OperatingPointDropDownLabel  matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       pt% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OperatingPointDropDown
        function OperatingPointDropDownValueChanged(app, event)
           app.pt = app.OperatingPointDropDown.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              %clear all;
              global QTank  n_st n_op n_ip% Define global variables
           operating_pt=app.pt;
           %ctrl_design_method=app.ctrl; 
          % Servo_Problem=app.Control_type;
          QTank.a1 = 0.071 ;    % cm^2 (Area of outlet tube for Tank 1)
QTank.A1 = 28    ;    % cm^2 (Area of tank 1)

QTank.a3 = 0.071 ;    % cm^2 (Area of outlet tube for Tank 3)
QTank.A3 = 28    ;    % cm^2 (Area of tank 3)

QTank.a2 = 0.057 ;    % cm^2 (Area of outlet tube for Tank 2)
QTank.A2 = 32    ;    % cm^2 (Area of tank 2)

QTank.a4 = 0.057 ;    % cm^2 (Area of outlet tube for Tank 4)
QTank.A4 = 32    ;    % cm^2 (Area of tank 4)

if ( operating_pt == 1 )                                  % Minimum phase system
    QTank.gam1 = 0.7 ;    % cm^3/Vs
    QTank.k1 =   3.33 ;    
    QTank.gam2 = 0.6 ;    % cm^3/Vs
    QTank.k2 =   3.35 ;
    QTank.Us = [ 3 3 ]' ;           % Steady state input 
else                           % Non-Minimum phase system 
    QTank.gam1 = 0.43 ;    % cm^3/Vs
    QTank.k1 =   3.14 ;
    QTank.gam2 = 0.34 ;    % cm^3/Vs
    QTank.k2 =   3.29 ;
    QTank.Us = [ 3.15 3.15 ]' ;   % Steady state input 
end
QTank.Ds = 2 ;    % Steady state disturbance flow input 
QTank.gam3 = 0.4 ;   % For disturbance input 
          % compensate=app.comp;
            if (operating_pt == 1)                         % Minimum phase system
                load QTank_MinPhase_Model_1 QTank

            else      % Non-Minimum phase system
                load QTank_NonMinPhase_Model_new QTank
            end
            samp_T=4;
            Ns=500;
            dk=0;
            n_st = 4 ;   % No. of states
            n_op =  2 ;   % No. of measured outputs
            n_ip = 2 ;   % No. of man. inputs
            n_d = 1;  % No. of disturbances

            % Create matrices for saving simulation results
            Xk = zeros(n_st, Ns) ;     % State Profiles
            Uk = zeros(n_ip, Ns) ;     % Input Profiles
            Dk = zeros(n_d, Ns) ;     % Disturbance Profile
            Yk = zeros(n_op, Ns) ;    % Output Profile

            uk = zeros(n_ip, Ns) ;     % Input Profiles (perturbation)
            yk = zeros(n_op, Ns) ;    % Output Profile (perturbation)


            % State Estimator Initialization
            xkpred = zeros(n_st, Ns) ; %  Create Dummy matrices for estimated states
            ek = zeros(n_op, Ns) ;        % Innovation sequence
            ek(:,1) = yk(:,1) - QTank.C_mat * xkpred(:,1) ;
            est_err = zeros(n_st, Ns) ;
            obs_poles=[0.7 0.8 0.9 0.8]';
            Lp_mat = place(QTank.phy', QTank.C_mat', obs_poles)  ;
            Lp_mat = Lp_mat' ;
            % EigenValues= eig( QTank.phy -  Lp_mat * QTank.C_mat );
            %  Innovation Bias Formulation initialization
            xsk = zeros(n_st, Ns) ;     % Matrices for saving terget states
            usk = zeros(n_ip, Ns) ;
            rk = zeros(n_op, Ns) ;
            ek_f = zeros(n_op, Ns) ;        % Filtered Innovation sequence
            alfa_e =0.7;
            phy_e = alfa_e * eye(n_op) ;
            alfa_r = 0.9;
            phy_r = alfa_r * eye(n_op) ;                  % Setpoint filter parameter

            % Marices required in Target state Computation
            Ku_mat = QTank.C_mat * inv(eye(n_st) - QTank.phy) * QTank.gama_u ;
            Ke_mat = QTank.C_mat * inv(eye(n_st) - QTank.phy) * Lp_mat +eye(n_op) ;

          %  if (ctrl_design_method == 1)
                con_poles = [ 0.7 0.8 0.8 0.9]' ;    % Closed loop poles
                G_mat = place(QTank.phy, QTank.gama_u, con_poles)  ;

          %  elseif (ctrl_design_method == 2)

               % Wx = eye(n_st) ;    % State weighting matrix

                %Wu = diag( [1  1]) ;     % Error weighting matrix
                % [G_mat, S_inf, EigVal_CL] = dlqr(QTank.phy, QTank.gama_u, Wx, Wu) ;

          %  elseif (ctrl_design_method == 3)

                % kx=1  ;
                % Wx = kx* eye(n_st) ;    % State weighting matrix
                % %%%%%Error weighting matrix
                % ku=1  ;
                % Wu =  ku * diag( [1  1]) ;     % Error weighting matrix
                % G_mat = Uncon_MPC(QTank.phy, QTank.gama_u,Wx,Wu,app.N_Prediction) ;
                %  EigVal_CL = eig( QTank.phy - QTank.gama_u * G_mat ) ;
          %  end
            Uk_Hi = [ 5 5 ]' ;
            Uk_Lo = [ 0 0]' ;
            uk_H = Uk_Hi - QTank.Us ;
            uk_L = Uk_Lo - QTank.Us ;
            % Setpoints
            rk = zeros(n_op, Ns) ;    % Deviation setpoint
            Rk = zeros(n_op, Ns) ;    % Setpoint trajectory in original variables

            % Process Initial conditions
            Xk(:,1) = QTank.Xs + 1 * [ -0.2 2 0.5 -0.5]' ;   % non-zero initial condition
            Yk(:,1) = QTank.C_mat * Xk(:,1)  ;

            % Perturbation Model: Initial conditions
            xk(:,1) = Xk(:,1) - QTank.Xs  ;
            yk(:,1) = Yk(:,1) - QTank.Ys   ;
            est_err(:,1) = xkpred(:,1) - xk(:,1);

            % Dynamic simulation of Quadruple Tank system under open loop onditions
            kT = zeros(1, Ns) ;  % Array to record time
            for k = 1 : Ns-1
                % Print sampling instant on screen
                kT(k) = (k-1) * samp_T ;         % update time array
                % if ( Servo_Problem == 1 )
                %     dk=0;
                %     if (k>= 100)
                %         rk(:,k)=[app.setpt1 app.setpt2];
                %     else
                %         rk(:,k) = [0 0]' ;
                %     end % Servo control
                % else %%%%Regulatory Problem
                %     rk(:,k) = zeros(n_op,1) ;
                %     dk=1;
                % end
                Rk(:,k) = QTank.Ys + rk(:,k) ;

                Dk(k) = QTank.Ds + dk ;      % Disturbance input

                % Compute  inputs at sampling instant k using pole placement state feedback controller

                % Generate filtered setpoint
                rk(:,k+1) = phy_r * rk(:,k) + (eye(n_op) - phy_r) * rk(:,k);

                % Compute target input and target states
                usk(:,k) = pinv(Ku_mat) * ( rk(:,k) - Ke_mat * ek_f(:,k) ) ;
                xsk(:,k) =  inv(eye(n_st) - QTank.phy) * ( QTank.gama_u * usk(:,k) +  Lp_mat * ek_f(:,k) ) ;

              %  if (compensate==1) 
                    uk(:,k) = usk(:,k)  - G_mat * (xkpred(:,k) - xsk(:,k)  )  ;
               %  elseif  (compensate==2) 
                  %  uk(:,k) = - G_mat * (xkpred(:,k));% - xsk(:,k)  )  ;
               % end

                if ( uk(1,k) <= uk_L(1) )   % Inpose input constraints on uk(1)
                    uk(1,k) = uk_L(1) ;
                elseif ( uk(1,k) >= uk_H(1) )
                    uk(1,k) = uk_H(1) ;
                end
                if ( uk(2,k) <= uk_L(2) ) % Inpose output constraints on uk(2)
                    uk(2,k) = uk_L(2) ;
                elseif ( uk(2,k) >= uk_H(2) )
                    uk(2,k) = uk_H(2) ;
                end
                Uk(:,k) =  uk(:,k) + QTank.Us   ;  % Manipulated input at instant k

                %  Luenberger Predictor computations from instant (k) to (k+1)
                yk(:,k )= Yk(:,k) - QTank.Ys ;
                ek(:,k) =  yk(:,k) - QTank.C_mat *  xkpred(:,k)  ;    % Compute Innovation
                xkpred(:,k+1) = QTank.phy * xkpred(:,k) + QTank.gama_u * uk(:,k) + Lp_mat * ek(:,k)  ;
                % Filtered innovation for innovation bias implementation
                ek_f(:,k+1) = phy_e * ek_f(:,k) + (eye(n_op) - phy_e) *  ek(:,k) ;

                % System Dynamics Simulation from sampling instant k to (k+1)

                % Plant Simulation using discete linear perturbation model
                xk(:,k+1) = QTank.phy * xk(:,k) + QTank.gama_u * uk(:,k) + QTank.gama_d * dk  ;
                yk(:,k+1) = QTank.C_mat * xk(:,k+1);
                Yk(:,k+1) =   yk(:,k+1) + QTank.Ys ;
                est_err(:,k+1) = xkpred(:,k+1) - xk(:,k+1);   % State estimation error
            end
            % specify input at the final instant :
            kT(Ns) = Ns * samp_T ;
            Uk(:,Ns)=   Uk(:,Ns-1) ;    % Input noise case
            Dk(Ns) = Dk(Ns-1) ;
            Rk(:,Ns) =  Rk(:,Ns-1) ;
            usk(:,Ns) = usk(:,Ns-1) ;
            xsk(:,Ns) = xsk(:,Ns-1)    ;
            plot(app.UIAxes,kT, Yk(1,:), '-k', kT, Rk(1,:), '-r')
            plot(app.UIAxes2, kT, Yk(2,:), '-k', kT, Rk(2,:), '-r')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.OperatingPointDropDownLabel = uilabel(app.UIFigure);
            app.OperatingPointDropDownLabel.Text = 'Operating Point';
            app.OperatingPointDropDown = uidropdown(app.UIFigure);
            app.OperatingPointDropDown.Items = {'Minimum Phase', 'NonMinimum Phase'};
            app.OperatingPointDropDown.ItemsData = {'1', '2'};
            app.OperatingPointDropDown.ValueChangedFcn = createCallbackFcn(app, @OperatingPointDropDownValueChanged, true);
            app.OperatingPointDropDown.Value = '1';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialSFC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end